classdef guiHighBall < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup2                    matlab.ui.container.TabGroup
        InputOutputTab               matlab.ui.container.Tab
        ManualControlPanel           matlab.ui.container.Panel
        Cutdown                      matlab.ui.control.Button
        ReleaseBallastPanel          matlab.ui.container.Panel
        MoveUpButton                 matlab.ui.control.Button
        AmountEditFieldLabel         matlab.ui.control.Label
        AmountBallast                matlab.ui.control.NumericEditField
        VentHeliumPanel              matlab.ui.container.Panel
        MoveDownButton               matlab.ui.control.Button
        AmountEditFieldLabel_2       matlab.ui.control.Label
        AmountHelium                 matlab.ui.control.NumericEditField
        TabGroup                     matlab.ui.container.TabGroup
        LocationandMotionTab         matlab.ui.container.Tab
        VelocityDirectionLabel       matlab.ui.control.Label
        vDirection                   matlab.ui.control.NumericEditField
        HorizontalVelocityLabel      matlab.ui.control.Label
        hVelocity                    matlab.ui.control.NumericEditField
        AccelerationDirectionLabel   matlab.ui.control.Label
        aDirection                   matlab.ui.control.NumericEditField
        HorizontalAccelerationLabel  matlab.ui.control.Label
        hAcceleration                matlab.ui.control.NumericEditField
        VerticalVelocityLabel        matlab.ui.control.Label
        vVelocity                    matlab.ui.control.NumericEditField
        VerticalAccelerationLabel    matlab.ui.control.Label
        vAcceleration                matlab.ui.control.NumericEditField
        LatitudeLabel                matlab.ui.control.Label
        Latitude                     matlab.ui.control.NumericEditField
        LongitudeLabel               matlab.ui.control.Label
        Longitude                    matlab.ui.control.NumericEditField
        AltitudeLabel                matlab.ui.control.Label
        Altitude                     matlab.ui.control.NumericEditField
        ConditionsTab                matlab.ui.container.Tab
        TemperatureLabel             matlab.ui.control.Label
        Temperature                  matlab.ui.control.NumericEditField
        BatteryLifeLabel             matlab.ui.control.Label
        BatteryLife                  matlab.ui.control.NumericEditField
        BallastRemainingLabel        matlab.ui.control.Label
        NumBallast                   matlab.ui.control.NumericEditField
        PressueLabel                 matlab.ui.control.Label
        Pressure                     matlab.ui.control.NumericEditField
        NoNewMessagesLampLabel       matlab.ui.control.Label
        NoNewMessagesLamp            matlab.ui.control.Lamp
        OkayButton                   matlab.ui.control.Button
        SentMessageListTab           matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        ExportDataButton             matlab.ui.control.Button
        RecievedDataGraphsTab        matlab.ui.container.Tab
        ExportDataButton_2           matlab.ui.control.Button
        DataTypeButtonGroup          matlab.ui.container.ButtonGroup
        AltitudeButton               matlab.ui.control.RadioButton
        PositionButton               matlab.ui.control.RadioButton
        TemperatureButton            matlab.ui.control.RadioButton
        PressureButton               matlab.ui.control.RadioButton
        BatteryButton                matlab.ui.control.RadioButton
        BallastButton                matlab.ui.control.RadioButton
        XAccelerationButton          matlab.ui.control.RadioButton
        YAccelerationButton          matlab.ui.control.RadioButton
        ZAccelerationButton          matlab.ui.control.RadioButton
        XVelocityButton              matlab.ui.control.RadioButton
        YVelocityButton              matlab.ui.control.RadioButton
        ZVelocityButton              matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RecievedQueue % Queue of reccieved messages with time stamps
        RecievedTimes % cell of time strings
        OutgoingMessage % message to payload
        OutgoingData % data to encode in message
        t
    end
    
    methods (Access = private)
        
        function DecodeMessage(app, file_name)
            FID = fopen(file_name);
            Encoded = fread(FID, 'ubit1');
            fclose('all');
            
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            
            app.RecievedQueue = [zeros(1,15); app.RecievedQueue];
            app.RecievedQueue(1,15) = date;
            
            % Temperature Data
            TEMP_MIN =  -88;
            TEMP_STEP = .25;
            TEMP_OFFSET = 128;
            TEMP_BITS = 9;
            app.RecievedQueue(1,1) = decode(app, TEMP_MIN, TEMP_STEP, Encoded(TEMP_OFFSET+1:TEMP_OFFET+TEMP_BITS+1));
            % Pressure Data
            PRS_MIN = 0;
            PRS_STEP = .25;
            PRS_OFFSET = 157;
            PRS_BITS = 12;
            app.RecievedQueue(1,2) = decode(app, PRS_MIN, PRS_STEP, Encoded(PRS_OFFSET+1:PRS_OFFET+PRS_BITS+1));
            % IMU Data
            ACC_MAG_MIN = 0;
            ACC_MAG_STEP = .001;
            ACC_MAG_OFFSET = 95;
            ACC_MAG_BITS = 14;
            AccMag = decode(app, ACC_MAG_MIN, ACC_MAG_STEP, Encoded(ACC_MAG_OFFSET+1:ACC_MAG_OFFET+ACC_MAG_BITS+1));
            
            ACC_THETA_MIN = 0;
            ACC_THETA_STEP = 0.0125663706;
            ACC_THETA_OFFSET = 109;
            ACC_THETA_BITS = 10;
            AccTheta = decode(app, ACC_THETA_MIN, ACC_THETA_STEP, Encoded(ACC_THETA_OFFSET+1:ACC_THETA_OFFET+ACC_THETA_BITS+1));
            
            ACC_PHI_MIN = 0;
            ACC_PHI_STEP = 0.00628318531;
            ACC_PHI_OFFSET = 119;
            ACC_PHI_BITS = 9;
            AccPhi = decode(app, ACC_PHI_MIN, ACC_PHI_STEP, Encoded(ACC_PHI_OFFSET+1:ACC_PHI_OFFET+ACC_PHI_BITS+1));
            % GPS Data
            ALT_MIN = -5000;
            ALT_STEP = 3;
            ALT_OFFSET = 0;
            ALT_BITS = 14;
            app.RecievedQueue(1,6) = decode(app, ALT_MIN, ALT_STEP, Encoded(ALT_OFFSET+1:ALT_OFFET+ALT_BITS+1));
            
            LAT_MIN = -100;
            LAT_STEP = .0001;
            LAT_OFFSET = 14;
            LAT_BITS = 23;
            app.RecievedQueue(1,7)= decode(app, LAT_MIN, LAT_STEP, Encoded(LAT_OFFSET+1:LAT_OFFET+LAT_BITS+1));
            
            LONG_MIN = 18;
            LONG_STEP = .0001;
            LONG_OFFSET = 37;
            LONG_BITS = 23;
            app.RecievedQueue(1,8) = decode(app, LONG_MIN, LONG_STEP, Encoded(LONG_OFFSET+1:LONG_OFFET+LONG_BITS+1));
            % Battery Data
            BATT_MIN = 0;
            BATT_STEP = .1;
            BATT_OFFSET = 147;
            BATT_BITS = 10;
            app.RecievedQueue(1,9) = decode(app, BATT_MIN, BATT_STEP, Encoded(BATT_OFFSET+1:BATT_OFFET+BATT_BITS+1));
            % Velovity Data
            VEL_MAG_MIN = 0;
            VEL_MAG_STEP = 0.001;
            VEL_MAG_OFFSET = 60;
            VEL_MAG_BITS = 16;
            VelMag = decode(app, VEL_MAG_MIN, VEL_MAG_STEP, Encoded(VEL_MAG_OFFSET+1:VEL_MAG_OFFET+VEL_MAG_BITS+1));
            
            VEL_THETA_MIN = 0;
            VEL_THETA_STEP = 0.0125663706;
            VEL_THETA_OFFSET = 76;
            VEL_THETA_BITS = 10;
            VelTheta = decode(app, VEL_THETA_MIN, VEL_THETA_STEP, Encoded(VEL_THETA_OFFSET+1:VEL_THETA_OFFET+VEL_THETA_BITS+1));
            
            VEL_PHI_MIN = 0;
            VEL_PHI_STEP = 0.00628318531;
            VEL_PHI_OFFSET = 86;
            VEL_PHI_BITS = 9;
            VelPhi = decode(app, VEL_PHI_MIN, VEL_PHI_STEP, Encoded(VEL_PHI_OFFSET+1:VEL_PHI_OFFET+VEL_PHI_BITS+1));
            % Ballast Data
            BALL_MIN = 0;
            BALL_STEP = .1;
            BALL_OFFSET = 137;
            BALL_BITS = 10;
            app.RecievedQueue(1,13) = decode(app, BALL_MIN, BALL_STEP, Encoded(BALL_OFFSET+1:BALL_OFFET+BALL_BITS+1));
            
            % Time Data
            TIME_MIN = 0;
            TIME_STEP = 1;
            TIME_OFFSET = 169;
            TIME_BITS = 17;
            app.RecievedQueue(1,14) = decode(app, TIME_MIN, TIME_STEP, Encoded(TIME_OFFSET+1:TIME_OFFET+TIME_BITS+1));
            
            app.RecievedQueue(1,3) = AccMag*cos(AccPhi);
            app.RecievedQueue(1,4) = AccMag*sin(AccPhi);
            app.RecievedQueue(1,5) = AccTheta;
            app.RecievedQueue(1,10) = VelMag*cos(VelPhi);
            app.RecievedQueue(1,11) = VelMag*sin(VelPhi);
            app.RecievedQueue(1,12) = VelTheta;
        
        end
        
        function value = decode(app, min, step, bin)
            input = bin2dec(bin);
            value = (input*step)+min;
        end
        
        function message = ManualHeliumVent(app)
            seconds = app.AmountHelium.Value;
            seconds_bin = int2bin(app, seconds);
            message = false(1,80);
            message(1:10) = true(1,10);
            message(end-length(seconds_bin)+1:end) = seconds_bin;
        end
        
        function message = ManualBallastDrop(app)
            drops = app.AmountBallast.Value;
            drops_bin = int2bin(app, drops);
            message = false(1,80);
            message(11:20) = true(1,10);
            message(end-length(drops_bin)+1:end) = drops_bin;
        end
        
        function message = ManualCutdown(app)
            message = false(1,80);
            message(21:30) = true(1,10);
        end
        
        function bin = int2bin(app, integer)
            l = ceil(log2(integer));
            integer = integer - 2^l;
            bin = true(1,l);
            for val = l-1:-1:1
                if (integer < 2^val)
                    bin(val) = false;
                else
                    integer = integer - 2^val;
                end
            end
        end
        
        function checkMessages(app)
            
            % Read Unread Emails in the folder
            mails = ReadOutlook(...
                'Folder', 'Senior Design SBD',...
                'Savepath', 'C:\Users\Kyle\Documents\GitHub\HighBall_Senior_Design\Software\Ground Communication\Incoming',...
                'Read',1 , ...
                'Mark',1);
            
            % Read data from incoming file and move it to another folder
            cd Incoming
            list = dir( '*data*.sbd');
            cd ..
            num = length(list);
            
            if num > 0
%                 message = sprintf('%d Incoming Message(s) Recieved!', num);
%                 uialert(app.UIFigure,message,'New Message',...
%                     'Icon','success');
                if num == 1
                    app.NoNewMessagesLampLabel.Text = '1 New Message';
                else
                    app.NoNewMessagesLampLabel.Text = [num2str(num), ' New Messages'];
                end
                app.NoNewMessagesLamp.Color = 'green';
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            else
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                disp(['Run CheckMessage @ ', date])
            end
            
            while(num > 0)
                name = ['Incoming/',list(num).name;];
                DecodeMessage(app, name);
                app.Altitude.Value = app.RecievedQueue(1,6);
                app.Longitude.Value = app.RecievedQueue(1,7);
                app.Latitude.Value = app.RecievedQueue(1,8);
                app.vAcceleration = app.RecievedQueue(1,3);
                app.hAcceleration = app.RecievedQueue(1,4);
                app.aDirection = app.RecievedQueue(1,5);
                app.vVelocity = app.RecievedQueue(1,10);
                app.hVelocity = app.RecievedQueue(1,11);
                app.vDirection = app.RecievedQueue(1,12);
                app.Temperature.Value = app.RecievedQueue(1,1);
                app.BatteryLife.Value = app.RecievedQueue(1,9);
                app.NumBallast .Value = app.RecievedQueue(1,13);
                app.Pressure.Value = app.RecievedQueue(1,2);
                app.RecievedTimes = [{date}; app.RecievedTimes];
                movefile(['Incoming/',list(num).name;], 'Read');
                num = num - 1;
            end
        end
        
        function SendMessage(app, fields)
            bin = app.OutgoingMessage;
            
            % Write data to binary .sbd filed
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            file_name = [date,'data.sbd'];
            FID = fopen(file_name, 'w+');
            fwrite(FID, bin, 'ubit1');
            fclose('all');
            
            % Send email with sbd Attachment
            
            % Define these variables appropriately:
            mail = 'teamHighBall@yahoo.com'; %Your Yahoo email address
            password = 'MITLLHighBall2018';  %Your Yahoo password
            setpref('Internet','SMTP_Server','smtp.mail.yahoo.com');
            setpref('Internet','E_mail',mail);
            setpref('Internet','SMTP_Username',mail);
            setpref('Internet','SMTP_Password',password);
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port','465');
            
            % to_mail =  'data@sbd.iridium.com'; % use this email for messages
            to_mail = 'kwatters6@gatech.edu'; % change to my email for testing
            % ISU = '300434062690230'; % ISU number here
            ISU = 'TestNumber';
            
            sendmail(to_mail, ISU, 'Message is Unrelated', file_name)
            
            % Move sent data to another folder and record data in queue
            movefile([date,'data.sbd'], 'Sent');
            app.UITable.Data = [cell(1,3); app.UITable.Data];
            app.UITable.Data(1,1:fields) = app.OutgoingData;
            app.OutgoingData = cell(0);
            app.OutgoingMessage = [];
        end
        
        function confirm_rem(app,src,event)
            display(event.SelectedOption);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RecievedTimes = cell(0);  % Queue of reccieved messages with time stamps
            app.RecievedQueue = [];
            app.OutgoingMessage = []; % message to payload
            app.OutgoingData = cell(0); % data to encode in message
            
            app.t = timer('ExecutionMode','fixedSpacing', ...
                'Name', 'CheckMailTimer', ...
                'TasksToExecute', Inf, ...
                'Period', 180);
            app.t.TimerFcn =  @(~, ~) app.checkMessages;
            start(app.t);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure,'Send Manual Ballast Drop Command?','Confirm',...
                'CloseFcn',@(src,event)confirm_rem(app,src,event));
            if isequal(choice, 'OK')
                app.OutgoingMessage = ManualBallastDrop(app);
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                app.OutgoingData = {'Ballast Drop', app.AmountBallast.Value, date};
                SendMessage(app, 3);
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure,'Send Manual Helium Vent Command?','Confirm',...
                'CloseFcn',@(src,event)confirm_rem(app,src,event));
            if isequal(choice, 'OK')
                app.OutgoingMessage = ManualHeliumVent(app);
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                app.OutgoingData = {'Helium Vent', app.AmountHelium.Value, date};
                SendMessage(app, 3);
            end
        end

        % Button pushed function: Cutdown
        function CutdownButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure,'Send Manual Cutdown Command?','Confirm Cutdown',...
                'CloseFcn',@(src,event)confirm_rem(app,src,event));
            if isequal(choice, 'OK')
                app.OutgoingMessage = ManualCutdown(app);
                temp = datetime('now','TimeZone','local');
                date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
                app.OutgoingData = {'Cutdown', '', date};
                SendMessage(app, 3);
            end
        end

        % Selection changed function: DataTypeButtonGroup
        function DataTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DataTypeButtonGroup.SelectedObject;
            switch selectedButton
                case 'Altitude'
                    plot(app.UIAxes, app.Recieved(:,14), app.RecievedQueue(:,6));
                    app.UIAxes.Title = 'Altitude';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Altitude';
                case 'Position'
                    plot(app.UIAxes, app.Recieved(:,7), app.RecievedQueue(:,8), '-o');
                    app.UIAxes.Title = 'Position';
                    app.UIAxes.Xlabel = 'Latitude';
                    app.UIAxes.Ylabel = 'Longitude';
                case 'Temperature'
                    plot(app.UIAxes, app.Recieved(:,14), app.RecievedQueue(:,1));
                    app.UIAxes.Title = 'Temperature';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Temperature';
                case 'Pressure'
                    plot(app.UIAxes, app.Recieved(:,14), app.RecievedQueue(:,2));
                    app.UIAxes.Title = 'Pressure';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Pressure';
                case 'Battery'
                    plot(app.UIAxes, app.Recieved(:,14), app.RecievedQueue(:,9));
                    app.UIAxes.Title = 'Percent Battery Remaining';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = '% Battery';
                case 'Ballast'
                    plot(app.UIAxes, app.Recieved(:,14), app.RecievedQueue(:,13));
                    app.UIAxes.Title = 'Percent Ballast Remaining';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = '% Ballast';
                case 'XAcceleration'
                    XAcc = app.Recieved(:,4).*cos(app.Recieved(:,5));
                    plot(app.UIAxes, app.Recieved(:,14), XAcc);
                    app.UIAxes.Title = 'X Acceleration';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'X Acceleration';
                case 'YAcceleration'
                    YAcc = app.Recieved(:,4).*sin(app.Recieved(:,5));
                    plot(app.UIAxes, app.Recieved(:,14), YAcc);
                    app.UIAxes.Title = 'Y Acceleration';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Y Acceleration';
                case 'ZAcceleration'
                    plot(app.UIAxes, app.Recieved(:,14), app.Recieved(:,3));
                    app.UIAxes.Title = 'Z Acceleration';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Z Acceleration';
                case 'XVelocity'
                    XVel = app.Recieved(:,11).*cos(app.Recieved(:,12));
                    plot(app.UIAxes, app.Recieved(:,14), XVel);
                    app.UIAxes.Title = 'X Velocity';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'X Velocity';
                case 'YVelocity'
                    YVel = app.Recieved(:,11).*sin(app.Recieved(:,12));
                    plot(app.UIAxes, app.Recieved(:,14), YVel);
                    app.UIAxes.Title = 'Y Velocity';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Y Velocity';
                case 'ZVelocity'
                    plot(app.UIAxes, app.Recieved(:,14), app.Recieved(:,10));
                    app.UIAxes.Title = 'Z Velocity';
                    app.UIAxes.Xlabel = 'Time Elapsed';
                    app.UIAxes.Ylabel = 'Z Velocity';
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Sent_Messages_', date, '.xlsx'];
            xlswrite(filename, app.UITable.Data);
        end

        % Button pushed function: ExportDataButton_2
        function ExportDataButton_2Pushed(app, event)
            temp = datetime('now','TimeZone','local');
            date = datestr(temp, 'yy-mm-dd_HH-MM-ss');
            filename = ['Recieved_Data_', date, '.xlsx'];
            Data_Cell = num2cell(app.RecievedQueue);
            Data_Cell = [Data_Cell; app.RecievedTimes];
            xlswrite(filename, Data_Cell);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.t);
        end

        % Button pushed function: OkayButton
        function OkayButtonPushed(app, event)
            app.NoNewMessagesLamp.Color = 'red';
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.InputOutputTab = uitab(app.TabGroup2);
            app.InputOutputTab.Title = 'Input Output';
            app.ManualControlPanel = uipanel(app.InputOutputTab);
            app.ManualControlPanel.Title = 'Manual Control';
            app.Cutdown = uibutton(app.ManualControlPanel, 'push');
            app.Cutdown.ButtonPushedFcn = createCallbackFcn(app, @CutdownButtonPushed, true);
            app.Cutdown.Text = 'Cutdown';
            app.ReleaseBallastPanel = uipanel(app.ManualControlPanel);
            app.ReleaseBallastPanel.Title = 'Release Ballast';
            app.MoveUpButton = uibutton(app.ReleaseBallastPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.AmountEditFieldLabel = uilabel(app.ReleaseBallastPanel);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountBallast = uieditfield(app.ReleaseBallastPanel, 'numeric');
            app.VentHeliumPanel = uipanel(app.ManualControlPanel);
            app.VentHeliumPanel.Title = 'Vent Helium';
            app.MoveDownButton = uibutton(app.VentHeliumPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.AmountEditFieldLabel_2 = uilabel(app.VentHeliumPanel);
            app.AmountEditFieldLabel_2.Text = 'Amount';
            app.AmountHelium = uieditfield(app.VentHeliumPanel, 'numeric');
            app.TabGroup = uitabgroup(app.InputOutputTab);
            app.LocationandMotionTab = uitab(app.TabGroup);
            app.LocationandMotionTab.Title = 'Location and Motion';
            app.VelocityDirectionLabel = uilabel(app.LocationandMotionTab);
            app.VelocityDirectionLabel.Text = 'Velocity Direction';
            app.vDirection = uieditfield(app.LocationandMotionTab, 'numeric');
            app.vDirection.Editable = 'off';
            app.HorizontalVelocityLabel = uilabel(app.LocationandMotionTab);
            app.HorizontalVelocityLabel.Text = 'Horizontal Velocity';
            app.hVelocity = uieditfield(app.LocationandMotionTab, 'numeric');
            app.hVelocity.Editable = 'off';
            app.AccelerationDirectionLabel = uilabel(app.LocationandMotionTab);
            app.AccelerationDirectionLabel.Text = 'Acceleration Direction';
            app.aDirection = uieditfield(app.LocationandMotionTab, 'numeric');
            app.aDirection.Editable = 'off';
            app.HorizontalAccelerationLabel = uilabel(app.LocationandMotionTab);
            app.HorizontalAccelerationLabel.Text = 'Horizontal Acceleration';
            app.hAcceleration = uieditfield(app.LocationandMotionTab, 'numeric');
            app.hAcceleration.Editable = 'off';
            app.VerticalVelocityLabel = uilabel(app.LocationandMotionTab);
            app.VerticalVelocityLabel.Text = 'Vertical Velocity';
            app.vVelocity = uieditfield(app.LocationandMotionTab, 'numeric');
            app.vVelocity.Editable = 'off';
            app.VerticalAccelerationLabel = uilabel(app.LocationandMotionTab);
            app.VerticalAccelerationLabel.Text = 'Vertical Acceleration';
            app.vAcceleration = uieditfield(app.LocationandMotionTab, 'numeric');
            app.vAcceleration.Editable = 'off';
            app.LatitudeLabel = uilabel(app.LocationandMotionTab);
            app.LatitudeLabel.Text = ' Latitude';
            app.Latitude = uieditfield(app.LocationandMotionTab, 'numeric');
            app.Latitude.Editable = 'off';
            app.LongitudeLabel = uilabel(app.LocationandMotionTab);
            app.LongitudeLabel.Text = 'Longitude';
            app.Longitude = uieditfield(app.LocationandMotionTab, 'numeric');
            app.Longitude.Editable = 'off';
            app.AltitudeLabel = uilabel(app.LocationandMotionTab);
            app.AltitudeLabel.Text = 'Altitude';
            app.Altitude = uieditfield(app.LocationandMotionTab, 'numeric');
            app.Altitude.Editable = 'off';
            app.ConditionsTab = uitab(app.TabGroup);
            app.ConditionsTab.Title = 'Conditions';
            app.TemperatureLabel = uilabel(app.ConditionsTab);
            app.TemperatureLabel.Text = 'Temperature';
            app.Temperature = uieditfield(app.ConditionsTab, 'numeric');
            app.Temperature.Editable = 'off';
            app.BatteryLifeLabel = uilabel(app.ConditionsTab);
            app.BatteryLifeLabel.Text = 'Battery Life';
            app.BatteryLife = uieditfield(app.ConditionsTab, 'numeric');
            app.BatteryLife.Editable = 'off';
            app.BallastRemainingLabel = uilabel(app.ConditionsTab);
            app.BallastRemainingLabel.Text = 'Ballast Remaining';
            app.NumBallast = uieditfield(app.ConditionsTab, 'numeric');
            app.NumBallast.Editable = 'off';
            app.PressueLabel = uilabel(app.ConditionsTab);
            app.PressueLabel.Text = 'Pressue';
            app.Pressure = uieditfield(app.ConditionsTab, 'numeric');
            app.Pressure.Editable = 'off';
            app.NoNewMessagesLampLabel = uilabel(app.InputOutputTab);
            app.NoNewMessagesLampLabel.Text = 'No New Messages';
            app.NoNewMessagesLamp = uilamp(app.InputOutputTab);
            app.OkayButton = uibutton(app.InputOutputTab, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @OkayButtonPushed, true);
            app.OkayButton.Text = 'Okay';
            app.SentMessageListTab = uitab(app.TabGroup2);
            app.SentMessageListTab.Title = 'Sent Message List';
            app.UITable = uitable(app.SentMessageListTab);
            app.UITable.ColumnName = {'Command'; 'Value'; 'Time'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.SentMessageListTab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.RecievedDataGraphsTab = uitab(app.TabGroup2);
            app.RecievedDataGraphsTab.Title = 'Recieved Data Graphs';
            app.ExportDataButton_2 = uibutton(app.RecievedDataGraphsTab, 'push');
            app.ExportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_2Pushed, true);
            app.ExportDataButton_2.Text = 'Export Data';
            app.DataTypeButtonGroup = uibuttongroup(app.RecievedDataGraphsTab);
            app.DataTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataTypeButtonGroupSelectionChanged, true);
            app.DataTypeButtonGroup.Title = 'Data Type';
            app.AltitudeButton = uiradiobutton(app.DataTypeButtonGroup);
            app.AltitudeButton.Text = 'Altitude';
            app.AltitudeButton.Value = true;
            app.PositionButton = uiradiobutton(app.DataTypeButtonGroup);
            app.PositionButton.Text = 'Position';
            app.TemperatureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.TemperatureButton.Text = 'Temperature';
            app.PressureButton = uiradiobutton(app.DataTypeButtonGroup);
            app.PressureButton.Text = 'Pressure';
            app.BatteryButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BatteryButton.Text = 'Battery';
            app.BallastButton = uiradiobutton(app.DataTypeButtonGroup);
            app.BallastButton.Text = 'Ballast';
            app.XAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.XAccelerationButton.Text = 'X Acceleration';
            app.YAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.YAccelerationButton.Text = 'Y Acceleration';
            app.ZAccelerationButton = uiradiobutton(app.DataTypeButtonGroup);
            app.ZAccelerationButton.Text = 'Z Acceleration';
            app.XVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.XVelocityButton.Text = 'X Velocity';
            app.YVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.YVelocityButton.Text = 'Y Velocity';
            app.ZVelocityButton = uiradiobutton(app.DataTypeButtonGroup);
            app.ZVelocityButton.Text = 'Z Velocity';
            app.UIAxes = uiaxes(app.RecievedDataGraphsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiHighBall

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end